classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IdentificacinPanel              matlab.ui.container.Panel
        NosehadetectadoningunacaraLabel  matlab.ui.control.Label
        VolverButton                    matlab.ui.control.Button
        TienesqueseleccionarunaimagenantesdecomenzarLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SeleccionarimagenButton         matlab.ui.control.Button
        ComenzarButton                  matlab.ui.control.Button
        EntrenamientoPanel              matlab.ui.container.Panel
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        RedneuronalButtonGroup          matlab.ui.container.ButtonGroup
        SqueezeNetButton                matlab.ui.control.RadioButton
        AlexNetButton                   matlab.ui.control.RadioButton
        EntrenarButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            miniBatchSize = app.MiniBatchSizeEditField.Value;
            maxEpochs = app.MaxEpochsEditField.Value;
            initialTraining = app.InitialLearnRateEditField.Value;
            method = app.RedneuronalButtonGroup.SelectedObject.Text;
            app.EntrenamientoPanel.Visible = 0;
            app.IdentificacinPanel.Visible = 1;
            CNNTrainingExpresionFacial227x227(method, miniBatchSize, maxEpochs, initialTraining);
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            app.EntrenamientoPanel.Visible = 1;
            app.IdentificacinPanel.Visible = 0;
        end

        % Button pushed function: SeleccionarimagenButton
        function SeleccionarimagenButtonPushed(app, event)
            app.NosehadetectadoningunacaraLabel.Visible = 0;
            filter={'*.jpg';'*.png'};
            [file,path] = uigetfile(filter);
            fileImage=fullfile(path,file);
            app.Image.ImageSource=fileImage;
            app.TienesqueseleccionarunaimagenantesdecomenzarLabel.Visible = 0;
        end

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
            if app.Image.ImageSource == ""
                app.TienesqueseleccionarunaimagenantesdecomenzarLabel.Visible = 1;
            else
                grabar = redimensionImagen(app.Image.ImageSource);
                if grabar == 1
                    extension = app.Image.ImageSource(1,end-3:end);
                    imagen = app.Image.ImageSource(:,1:end-4);
                    resize = 'face277x277';
                    imagen = [imagen,resize,extension];
                    clasificarImagen(imagen,app.RedneuronalButtonGroup.SelectedObject.Text);
                else
                    app.NosehadetectadoningunacaraLabel.Visible = 1;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EntrenamientoPanel = uipanel(app.UIFigure);
            app.EntrenamientoPanel.Title = 'Entrenamiento';
            app.EntrenarButton = uibutton(app.EntrenamientoPanel, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.RedneuronalButtonGroup = uibuttongroup(app.EntrenamientoPanel);
            app.RedneuronalButtonGroup.Title = 'Red neuronal';
            app.AlexNetButton = uiradiobutton(app.RedneuronalButtonGroup);
            app.AlexNetButton.Text = 'AlexNet';
            app.AlexNetButton.Value = true;
            app.SqueezeNetButton = uiradiobutton(app.RedneuronalButtonGroup);
            app.SqueezeNetButton.Text = 'SqueezeNet';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.EntrenamientoPanel);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.EntrenamientoPanel, 'numeric');
            app.MiniBatchSizeEditField.Value = 8;
            app.MaxEpochsEditFieldLabel = uilabel(app.EntrenamientoPanel);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.EntrenamientoPanel, 'numeric');
            app.MaxEpochsEditField.Value = 20;
            app.InitialLearnRateEditFieldLabel = uilabel(app.EntrenamientoPanel);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.EntrenamientoPanel, 'numeric');
            app.InitialLearnRateEditField.Value = 0.0003;
            app.IdentificacinPanel = uipanel(app.UIFigure);
            app.IdentificacinPanel.Title = 'Identificación';
            app.IdentificacinPanel.Visible = 'off';
            app.ComenzarButton = uibutton(app.IdentificacinPanel, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.SeleccionarimagenButton = uibutton(app.IdentificacinPanel, 'push');
            app.SeleccionarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarimagenButtonPushed, true);
            app.SeleccionarimagenButton.Text = 'Seleccionar imagen';
            app.Image = uiimage(app.IdentificacinPanel);
            app.TienesqueseleccionarunaimagenantesdecomenzarLabel = uilabel(app.IdentificacinPanel);
            app.TienesqueseleccionarunaimagenantesdecomenzarLabel.Visible = 'off';
            app.TienesqueseleccionarunaimagenantesdecomenzarLabel.Text = 'Tienes que seleccionar una imagen antes de comenzar';
            app.VolverButton = uibutton(app.IdentificacinPanel, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.NosehadetectadoningunacaraLabel = uilabel(app.IdentificacinPanel);
            app.NosehadetectadoningunacaraLabel.Visible = 'off';
            app.NosehadetectadoningunacaraLabel.Text = 'No se ha detectado ninguna cara';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end